classdef assingment1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RecognizedTextArea       matlab.ui.control.TextArea
        RecognizedTextAreaLabel  matlab.ui.control.Label
        Button_2                 matlab.ui.control.Button
        ThresholdSlider          matlab.ui.control.Slider
        ThresholdSliderLabel     matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        LoadButton               matlab.ui.control.Button
        ComputeButton            matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
        ImageData
        Threshold = 128
        Mode = 1
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function applyProcessing(app)
            if isempty(app.ImageData)
                uialert(app.UIFigure, 'No image loaded.', 'Error');
                return;
            end
            switch app.Mode
                case 1
                    grayImage = rgb2gray(app.ImageData);
                    normalizedThreshold = app.Threshold / 255;
                    binaryImage = imbinarize(grayImage, normalizedThreshold);
                    imshow(binaryImage, 'Parent', app.UIAxes);
                case 2
                    grayImage = rgb2gray(app.ImageData);
                    edgeImage = edge(grayImage, 'Canny');
                    imshow(edgeImage, 'Parent', app.UIAxes);
                case 3
                    gaussianImage = imgaussfilt(app.ImageData, 2);
                    imshow(gaussianImage, 'Parent', app.UIAxes);
                case 4
                    % Display original image first
                    imshow(app.ImageData, 'Parent', app.UIAxes);
                    % Perform OCR and update RecognizedTextArea
                    ocrResults = ocr(app.ImageData);
                    recognizedText = ocrResults.Text;
                    app.RecognizedTextArea.Value = recognizedText;
            end
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            if isempty(app.ImageData)
                uialert(app.UIFigure, 'No image loaded. Please load an image first.', 'Error');
                return;
            end
            app.applyProcessing();
            if app.Mode == 1
                app.ThresholdSlider.Visible = 'on';
                app.ThresholdSliderLabel.Visible = 'on';
            else
                app.ThresholdSlider.Visible = 'off';
                app.ThresholdSliderLabel.Visible = 'off';
            end
        
            % Manage visibility of RecognizedTextArea and its label
            if app.Mode == 4
                % Ensure the text area is updated and then visible
                app.RecognizedTextArea.Visible = 'on';
                app.RecognizedTextAreaLabel.Visible = 'on';
            else
                app.RecognizedTextArea.Visible = 'off';
                app.RecognizedTextAreaLabel.Visible = 'off';
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.bmp','Image Files'});
            if isequal(file, 0)
                return;
            end
            fullPath = fullfile(path, file);
            app.ImageData = imread(fullPath);
            imshow(app.ImageData, 'Parent', app.UIAxes);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Mode = mod(app.Mode, 4) + 1;
            app.updateButtonLabel();
            
            if app.Mode ~= 4
                app.RecognizedTextArea.Visible = 'off';
                app.RecognizedTextAreaLabel.Visible = 'off';
            end
        
            if app.Mode ~= 1
                app.ThresholdSlider.Visible = 'off';
                app.ThresholdSliderLabel.Visible = 'off';
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Mode = mod(app.Mode - 2,4) + 1;
            app.updateButtonLabel();
            if app.Mode ~= 1
                app.ThresholdSlider.Visible = 'off';
                app.ThresholdSliderLabel.Visible = 'off';
            end
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            app.Threshold = app.ThresholdSlider.Value;
            app.ThresholdSliderLabel.Text = ['Threshold: ' num2str(app.Threshold)];
            if app.Mode == 1
                app.applyProcessing();
            end
        end

        % Button down function: UIAxes
        function updateButtonLabel(app, event)
            switch app.Mode
                case 1
                    app.ComputeButton.Text = 'Compute';
                case 2
                    app.ComputeButton.Text = 'Compute2';
                case 3
                    app.ComputeButton.Text = 'Compute3';
                case 4
                    app.ComputeButton.Text = 'Compute4';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @updateButtonLabel, true);
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '⇧';
            app.ThresholdSliderLabel = uilabel(app.UIFigure);
            app.ThresholdSliderLabel.Visible = 'off';
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.UIFigure);
            app.ThresholdSlider.Limits = [0 255];
            app.ThresholdSlider.MajorTicks = [0 50 100 150 200 255];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.Visible = 'off';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '⇩';
            app.RecognizedTextAreaLabel = uilabel(app.UIFigure);
            app.RecognizedTextAreaLabel.Visible = 'off';
            app.RecognizedTextAreaLabel.Text = 'RecognizedTextArea';
            app.RecognizedTextArea = uitextarea(app.UIFigure);
            app.RecognizedTextArea.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assingment1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @applyProcessing)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end